classdef EasyEgyEst_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EnergyConsumptionEstimatorv10UIFigure  matlab.ui.Figure
        Label_ECR_est_14          matlab.ui.control.Label
        Label_ECR_est_13          matlab.ui.control.Label
        Label_ECR_est_12          matlab.ui.control.Label
        Label_ECR_est_11          matlab.ui.control.Label
        Label_ECR_est_10          matlab.ui.control.Label
        Label_ECR_est_9           matlab.ui.control.Label
        Label_GECR_sum            matlab.ui.control.Label
        EstimateButton            matlab.ui.control.Button
        constspeedSlider          matlab.ui.control.Slider
        ButtonGroup_targetspeed   matlab.ui.container.ButtonGroup
        TargetspeedButton         matlab.ui.control.RadioButton
        ConstspeedButton          matlab.ui.control.RadioButton
        Image2                    matlab.ui.control.Image
        Label_ECR_est_7           matlab.ui.control.Label
        Label_ECR_est_6           matlab.ui.control.Label
        Label_ECR_est_5           matlab.ui.control.Label
        Label_ECR_est_4           matlab.ui.control.Label
        Label_ECR_est             matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
        ButtonGroup               matlab.ui.container.ButtonGroup
        SelectButton              matlab.ui.control.RadioButton
        CLTCButton                matlab.ui.control.RadioButton
        Label_target_cyclename    matlab.ui.control.Label
        TargetdrivingcycleButton  matlab.ui.control.Button
        Labe_base                 matlab.ui.control.Label
        BasedrivingcycleButton    matlab.ui.control.Button
        Image                     matlab.ui.control.Image
        BEVModelDropDown          matlab.ui.control.DropDown
        BEVModelDropDownLabel     matlab.ui.control.Label
        UIAxesTargetSpeedProfile  matlab.ui.control.UIAxes
        UIAxes_3Dcylinder         matlab.ui.control.UIAxes
        UIAxesBaseSpeedProfile    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        color = [  201 177 213;...
                   165 205 226;...
                   252 190 111;...
                   250 153 152;...
                   177 222 137]/255;
        
        num_EV_model % Description
        name_EV_model
        GECR_EV_model
        ABCm_EV_model


        % parameters for calculate features 
        para
        spd_lv = 15

        basefilename_default = 'CLTC_P.csv';
        CyclePath = '.\cycles';

        % initialization
        feature_init = [nan, nan, nan, nan, nan];
        ECRs_init = [nan, nan, nan, nan, nan];
        
        % generalized energy consumption rates
        GECR_default
        GECR
        

        % driving features
        I_defualt
        I_base
        I_target
        I_norm_target
        
        % energy consumption
        ECRs_target
        ECR_target
        
        flag_I_base_update = 0;


        Table_result
        
    end
    
    methods (Access = public)   
        
        %% ===============

            
        function ECR_str = ECR2str(app,ECR)
            if ~sum(isnan(ECR))
                ECR_str = string(num2str(flip(ECR'),'%.1f'));
            else
                ECR_str = strings(5,1);
            end
        end

        function I_str = I2str(app,I)
            if ~sum(isnan(I))
                I_str = string(num2str(flip(I'),'%.2f'));
                I_str(end) = "";
            else
                I_str = strings(5,1);
            end
        end


        % ----------------speed_profile_read----------------- %
        function [t,v,ts] = speed_profile_read(app,filename)
            T = readmatrix(filename);
            num_sample = length(T);
            if num_sample<1
                warndlg('No driving data found!','error')
                t = NaN;
                v = NaN;
                ts = NaN;
            else
                num_var = width(T);
                if num_var~=2
                    warndlg("The data file format is incorrect! The required format expects the first column to be evenly spaced time intervals, the second column to be corresponding speeds, and all other columns to be empty.",'Error');
                    t = NaN;
                    v = NaN;
                    ts = NaN;
                else
            

                    t = T(:,1);
                    t = t - t(1);
                    v = T(:,2)/3.6;   % km/h-->m/s
        
                    % -----check cycle sample periods-----
                    t_err = 0.01;
                    ts = t(2);
                    dt = t(2:end)-t(1:end-1)-ts;
                    chkdt = dt<-t_err | dt>t_err;
                    if sum(chkdt)>0
                        warndlg("The sampling intervals are inconsistent in the driving data! The estimation results might be affected",'Warning');
                    end
                    if v(1)~=v(end)
                        warndlg("The start and end speeds are not equal! The estimation results might be affected",'Warning');
                    end
                end
            end
        end
        
        % ----------------driving_feature_cal(app, ts,v,para,spd_lv)---------------- %
        function F = driving_feature_cal(app, ts,v,para,spd_lv)
            num_sample = length(v);
            if num_sample>1
                v_bar = ((v(1:end-1)+v(2:end))/2); 
                % speed intensity
                I_spd = sum(v_bar.^3)/sum(v_bar);
                % braking intensity
                A = para(1);      % N
                B = para(2)*3.6;      % N/(m/s)
                C = para(3)*3.6^2;    % N/(m/s)^2
                m = para(4);       % kg
                
                E_brk = nan(num_sample-1,1);
                for i = 1:num_sample-1
                    E_brk(i) = 0.5*m*(v(i)^2-v(i+1)^2)-0.5*A*ts*(v(i)+v(i+1))-1/3*B*ts*(v(i)^2+v(i+1)^2+v(i)*v(i+1))-1/4*C*ts*(v(i)^3+v(i)^2*v(i+1)+v(i)*v(i+1)^2+v(i+1)^3);
                    if E_brk(i) <0
                        E_brk(i) =0;
                    end
                end
                ind_lv =  v_bar>spd_lv;    
                I_brk_hi = sum(E_brk(ind_lv)/3600)/sum(v_bar*ts/1000);
                I_brk_lo = sum(E_brk(~ind_lv)/3600)/sum(v_bar*ts/1000);
                % slow-driving intensity
                I_slw = 1/mean(v_bar);
            else
                    v_bar = v;
                    I_spd = v_bar.^2;
                    I_brk_hi = 0;
                    I_brk_lo = 0;
                    I_slw = 1/mean(v_bar);
            end
            F = [1, I_spd,I_brk_hi,I_brk_lo,I_slw];
        end
        
        % ----------------SpeedProfilePlot(app,Axes,t,v)---------------- %
        function SpeedProfilePlot(app,Axes,t,v)
            Axes.Visible = "on";
            if length(v)==1
                plot(Axes,[0 t],[v*3.6 v*3.6])
                xlim(Axes,[0,t])
            else
                plot(Axes,t,v*3.6)
                xlim(Axes,[min(t),max(t)])
            end
            
            xlabel(Axes,'Time [s]')
            ylabel(Axes,'Speed [km/h]')
            grid(Axes,"on")
        end

        % ----------------Cylinder3DPlot(app, ECRs_,Axes)---------------- %
        function Cylinder3DPlot(app, ECRs_, Axes)
            
            % visualization
            R = 45;
            fontsize = 16;
            
            
            num_ECRs = length(ECRs_);
            for i = 1:num_ECRs
                if ECRs_(i)==0
                    ECRs_(i) = 1e-3;
                end
            end

            offset = [0 cumsum(ECRs_)];
            
            for i = 1:num_ECRs
                gm = multicylinder(R,ECRs_(i),ZOffset=offset(i));
                p = pdegplot(Axes,gm,FaceAlpha=0.5);
                hold(Axes,'on');
                % 
                p(1).FaceColor = app.color(i,:);
                p(2).LineStyle = 'none';
            end
            hold(Axes,'off');
            view(Axes,18.5203,18)
            
            xlim(Axes,[-1.1*R,1.1*R]);
            ylim(Axes,[-1.1*R,1.1*R]);
            zlim(Axes,[0,350])
            zlabel(Axes,{'Estimated ECR','[Wh/km]'},'FontSize',fontsize)

            axis(Axes,'off')
            disableDefaultInteractivity(Axes)
            Axes.Toolbar.Visible = 'off'; 
        end

        %% =============== 
        % ----------------BEV init----------------------- %


        % ----------------BaseCYC init------------------- %
        function base_cycle_init(app)
            app.CLTCButton.Value = 1;
            app.BasedrivingcycleButton.Enable = "off";   
            filename = app.basefilename_default;

            [t,v,ts] = speed_profile_read(app,filename);
            if ~isnan(t)                  
                app.I_defualt = driving_feature_cal(app, ts,v, app.para,app.spd_lv);
            end
        end

        % ----------------TargetCYC init----------------- %
        function target_cycle_init(app)
            % clear figure plot and cycle information
            cla(app.UIAxesTargetSpeedProfile);
            app.Label_target_cyclename.Text = '';
            % app.flag_I_base_update = 0;
            app.constspeedSlider.Enable = "off";
            app.TargetspeedButton.Value = 1;
            app.TargetdrivingcycleButton.Enable = "on";

            app.I_target =  app.feature_init;
        end


        % ----------------Estimation init---------------- %
        function estimation_init(app)
            app.I_norm_target = app.feature_init;
            app.ECRs_target = app.ECRs_init;
            app.ECR_target = nan;
        end

        % ---------------Table init ----------------------%
        function Table_init(app)
            % var_discription = {'Slow-driving Sensitivity × Slow-driving Intensity';'Braking Sensitivity(L)×Braking Intensity(L)';'Braking Sensitivity(H)×Braking Intensity(H)';'Speed Sensitivity×Speed Intensity';'ECR constant'};
            strname_GECR = {'Slow-driving Sensitivity';'Braking Sensitivity(L)';'Braking Sensitivity(H)';'Speed Sensitivity';'ECR constant'};
            strname_I = {'Slow-driving Intensity';'Braking Intensity(L)';'Braking Intensity(H)';'Speed Intensity';''};
            var_names ={'GECR name','GECR [Wh/km]','Driving Featuer Name','Driving Features [-]','EC[Wh/km]'};
            GECR_str = ECR2str(app,app.GECR);
            I_str = strings(5,1);
            ECR_str = strings(5,1);
            app.Table_result = table(strname_GECR,GECR_str,strname_I,I_str,ECR_str,'VariableNames',var_names);
            
            app.UITable.ColumnWidth = {'2x','1x','2x','1x','1x'};
            app.UITable.Data = app.Table_result;
            alignstyle = uistyle('HorizontalAlignment','center');
            addStyle(app.UITable,alignstyle,"column",[2 4 5])
            alignstyle = uistyle('HorizontalAlignment','left');
            addStyle(app.UITable,alignstyle,"column",[1 3])
            alignstyle = uistyle('FontWeight','bold');
            addStyle(app.UITable,alignstyle)
            alignstyle = uistyle('BackgroundColor',[0.94,0.94,0.94]);
            addStyle(app.UITable,alignstyle,"column",[1 3])
            
            for i = 1:5
                alignstyle = uistyle('BackgroundColor',app.color(6-i,:));
                addStyle(app.UITable,alignstyle ,'cell',[i,5])
            end
            app.UITable.FontSize = 18;
        end


        %% =============== 
        % ----------------BEV update----------------------- %
        function bev_update(app)
            app.GECR_default = app.GECR_EV_model(app.BEVModelDropDown.Value,:);
            app.GECR = app.GECR_default;
            app.para = app.ABCm_EV_model(app.BEVModelDropDown.Value,:);
            
            Table_init(app)

            base_cycle_init(app);
            base_cycle_update(app,[],app.basefilename_default);
            app.I_defualt = app.I_base;
            target_cycle_init(app);

            
            cla(app.UIAxes_3Dcylinder);
        end

        % ----------------BaseCYC update------------------- %
        function base_cycle_update(app,location,file)

            I_base_old = app.I_base;
            
            filename = fullfile(location,file);

            [t,v,ts] = speed_profile_read(app,filename);
            if ~isnan(t)                  
                app.I_base = driving_feature_cal(app, ts,v, app.para,app.spd_lv);
            end
            if any(~app.I_base)
                warndlg("A driving feature equals to zero! The base cycle is reset",'error');
                app.I_base = I_base_old;
            else
                if ~isempty(app.I_defualt)
                    app.GECR = app.GECR_default.*app.I_base./app.I_defualt;
                end
                SpeedProfilePlot(app,app.UIAxesBaseSpeedProfile,t,v);
                app.Labe_base.Text = file; 
                claTable(app);

            end
        end

        % ----------------TargetCYC update----------------- %
        function target_cycle_update(app,ts,t,v,cycname)
              app.I_target = driving_feature_cal(app, ts,v,app.para,app.spd_lv);
              SpeedProfilePlot(app,app.UIAxesTargetSpeedProfile,t,v);
              app.Label_target_cyclename.Text = cycname;  

        end

        % ----------------Estimation update---------------- %
        function estimation_update(app)
            if sum(isnan(app.I_target))
                warndlg("The target driving profile is not specified",'error');
            else
                app.I_norm_target = app.I_target./app.I_base;
                app.ECRs_target = app.GECR.*app.I_norm_target;  %
              
                app.ECRs_target = round(app.ECRs_target*10)/10;
                app.ECR_target = sum(app.ECRs_target);
     
                Table_update(app)
                Cylinder3DPlot(app, app.ECRs_target,app.UIAxes_3Dcylinder)
            end


        end

        function Table_update(app)
            GECR_str = ECR2str(app,app.GECR);
            I_str = I2str(app,app.I_norm_target);
            ECR_str = ECR2str(app,app.ECRs_target);
            app.Table_result{:,2} = GECR_str;
            app.Table_result{:,4} = I_str;
            app.Table_result{:,5} = ECR_str;

            app.Label_GECR_sum.Text = num2str(sum(app.GECR),'%.1f');

            app.UITable.Data = app.Table_result;
            if isnan(app.ECR_target)
                % app.Label_ECR_est.Text = "     Wh/km";
            else
                % app.Label_ECR_est.Text = strcat(num2str(app.ECR_target,'%.1f')," Wh/km");
                app.Label_ECR_est.Text = num2str(app.ECR_target,'%.1f');
            end
        end

        function claTable(app)
            app.I_norm_target = app.feature_init;
            app.ECRs_target = app.ECRs_init;
            app.ECR_target = nan;

            Table_update(app)
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            filename_GECR = "GECR data";
            T = readtable(filename_GECR);
            app.num_EV_model = height(T);
            app.name_EV_model = T.model_name;
            app.GECR_EV_model = T{:,2:6};
            app.ABCm_EV_model = T{:,7:end};
            % filename_base_cycle = "cycles\CLTC_P.csv";
            % filename_obj_cycle = "cycles\WLTC.csv";
            app.BEVModelDropDown.Items = app.name_EV_model;
            app.BEVModelDropDown.ItemsData=1:numel(app.BEVModelDropDown.Items);         

                 
            disableDefaultInteractivity(app.UIAxesBaseSpeedProfile)
            disableDefaultInteractivity(app.UIAxesTargetSpeedProfile)
            disableDefaultInteractivity(app.UIAxes_3Dcylinder)
            app.UIAxes_3Dcylinder.Toolbar.Visible = 'off'; 

            bev_update(app)
            
            
        end

        % Button pushed function: BasedrivingcycleButton
        function BasedrivingcycleButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [file,location] = uigetfile('*.*', 'Select a speed profile', app.CyclePath);
            delete(f); %delete the dummy figure
            if ~isequal(file,0)
                claTable(app);
                cla(app.UIAxes_3Dcylinder);

                base_cycle_update(app,location,file);
                
            end
        end

        % Button pushed function: TargetdrivingcycleButton
        function TargetdrivingcycleButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [file,location] = uigetfile('*.*', 'Select a speed profile', app.CyclePath);
            delete(f); %delete the dummy figure
            if ~isequal(file,0)
                
                claTable(app);
                cla(app.UIAxes_3Dcylinder);    

                filename = fullfile(location,file);
                [t,v,ts] = speed_profile_read(app,filename);
                target_cycle_update(app,ts,t,v,file)


            end
        end

        % Button pushed function: EstimateButton
        function EstimateButtonPushed(app, event)
            estimation_update(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            % selectedButton = app.ButtonGroup.SelectedObject;
            if app.CLTCButton.Value
                claTable(app);
                cla(app.UIAxes_3Dcylinder);
                % speed feature init
                base_cycle_init(app);
                base_cycle_update(app,[],app.basefilename_default);   
                % ECR init
             else
                app.BasedrivingcycleButton.Enable = "on";
            end
        end

        % Value changed function: BEVModelDropDown
        function BEVModelDropDownValueChanged(app, event)
            bev_update(app);
        end

        % Selection changed function: ButtonGroup_targetspeed
        function ButtonGroup_targetspeedSelectionChanged(app, event)
            % selectedButton = app.ButtonGroup_targetspeed.SelectedObject;
            if app.TargetspeedButton.Value
                app.TargetdrivingcycleButton.Enable = "on";
                app.constspeedSlider.Enable = "off";
            else
                app.constspeedSlider.Enable = "on";
                app.TargetdrivingcycleButton.Enable = "off";
            end
        end

        % Value changing function: constspeedSlider
        function constspeedSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Label_target_cyclename.Text = strcat('constant speed = ', int2str(floor(changingValue))," km/h");
        end

        % Value changed function: constspeedSlider
        function constspeedSliderValueChanged(app, event)
            claTable(app);
            cla(app.UIAxes_3Dcylinder);    

            v = floor(app.constspeedSlider.Value)/3.6;
            ts = 1;
            t = 600;
            cycname = strcat('constant speed = ', int2str(app.constspeedSlider.Value)," km/h");
            target_cycle_update(app,ts,t,v,cycname);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.EnergyConsumptionEstimatorv10UIFigure = uifigure('Visible', 'off');
            app.EnergyConsumptionEstimatorv10UIFigure.Name = 'Energy Consumption Estimator v1.0';
            app.EnergyConsumptionEstimatorv10UIFigure.Icon = fullfile(pathToMLAPP, 'icon.png');
            app.UIAxesBaseSpeedProfile = uiaxes(app.EnergyConsumptionEstimatorv10UIFigure);
            app.UIAxesBaseSpeedProfile.Visible = 'off';
            app.UIAxes_3Dcylinder = uiaxes(app.EnergyConsumptionEstimatorv10UIFigure);
            app.UIAxes_3Dcylinder.Visible = 'off';
            app.UIAxesTargetSpeedProfile = uiaxes(app.EnergyConsumptionEstimatorv10UIFigure);
            app.UIAxesTargetSpeedProfile.Visible = 'off';
            app.BEVModelDropDownLabel = uilabel(app.EnergyConsumptionEstimatorv10UIFigure);
            app.BEVModelDropDownLabel.Text = 'BEV Model';
            app.BEVModelDropDown = uidropdown(app.EnergyConsumptionEstimatorv10UIFigure);
            app.BEVModelDropDown.ValueChangedFcn = createCallbackFcn(app, @BEVModelDropDownValueChanged, true);
            app.Image = uiimage(app.EnergyConsumptionEstimatorv10UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'new_logo_trans.png');
            app.BasedrivingcycleButton = uibutton(app.EnergyConsumptionEstimatorv10UIFigure, 'push');
            app.BasedrivingcycleButton.ButtonPushedFcn = createCallbackFcn(app, @BasedrivingcycleButtonPushed, true);
            app.BasedrivingcycleButton.Text = 'Base driving cycle';
            app.Labe_base = uilabel(app.EnergyConsumptionEstimatorv10UIFigure);
            app.Labe_base.Text = '';
            app.TargetdrivingcycleButton = uibutton(app.EnergyConsumptionEstimatorv10UIFigure, 'push');
            app.TargetdrivingcycleButton.ButtonPushedFcn = createCallbackFcn(app, @TargetdrivingcycleButtonPushed, true);
            app.TargetdrivingcycleButton.Text = 'Target driving cycle';
            app.Label_target_cyclename = uilabel(app.EnergyConsumptionEstimatorv10UIFigure);
            app.Label_target_cyclename.Text = '';
            app.ButtonGroup = uibuttongroup(app.EnergyConsumptionEstimatorv10UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.CLTCButton = uiradiobutton(app.ButtonGroup);
            app.CLTCButton.Text = ' CLTC';
            app.CLTCButton.Value = true;
            app.SelectButton = uiradiobutton(app.ButtonGroup);
            app.SelectButton.Text = ' Select';
            app.UITable = uitable(app.EnergyConsumptionEstimatorv10UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'column';
            app.UITable.Multiselect = 'off';
            app.UITable.Enable = 'off';
            app.Label_ECR_est = uilabel(app.EnergyConsumptionEstimatorv10UIFigure);
            app.Label_ECR_est.Text = '';
            app.Label_ECR_est_4 = uilabel(app.EnergyConsumptionEstimatorv10UIFigure);
            app.Label_ECR_est_4.Text = 'GECRs [Wh/km]                  ×                 Driving Feature [-]                      =';
            app.Label_ECR_est_5 = uilabel(app.EnergyConsumptionEstimatorv10UIFigure);
            app.Label_ECR_est_5.Text = '';
            app.Label_ECR_est_6 = uilabel(app.EnergyConsumptionEstimatorv10UIFigure);
            app.Label_ECR_est_6.Text = 'ECR [Wh/km]';
            app.Label_ECR_est_7 = uilabel(app.EnergyConsumptionEstimatorv10UIFigure);
            app.Label_ECR_est_7.Text = 'Energy Consumption Estimator Ver 1.0';
            app.Image2 = uiimage(app.EnergyConsumptionEstimatorv10UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'ecocar.png');
            app.ButtonGroup_targetspeed = uibuttongroup(app.EnergyConsumptionEstimatorv10UIFigure);
            app.ButtonGroup_targetspeed.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_targetspeedSelectionChanged, true);
            app.ConstspeedButton = uiradiobutton(app.ButtonGroup_targetspeed);
            app.ConstspeedButton.Text = 'Const speed';
            app.TargetspeedButton = uiradiobutton(app.ButtonGroup_targetspeed);
            app.TargetspeedButton.Text = 'Select';
            app.TargetspeedButton.Value = true;
            app.constspeedSlider = uislider(app.EnergyConsumptionEstimatorv10UIFigure);
            app.constspeedSlider.Limits = [5 200];
            app.constspeedSlider.MajorTicks = [0 50 100 150 200];
            app.constspeedSlider.ValueChangedFcn = createCallbackFcn(app, @constspeedSliderValueChanged, true);
            app.constspeedSlider.ValueChangingFcn = createCallbackFcn(app, @constspeedSliderValueChanging, true);
            app.constspeedSlider.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200];
            app.constspeedSlider.Value = 5;
            app.EstimateButton = uibutton(app.EnergyConsumptionEstimatorv10UIFigure, 'push');
            app.EstimateButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateButtonPushed, true);
            app.EstimateButton.Text = 'Estimate';
            app.Label_GECR_sum = uilabel(app.EnergyConsumptionEstimatorv10UIFigure);
            app.Label_GECR_sum.Text = '';
            app.Label_ECR_est_9 = uilabel(app.EnergyConsumptionEstimatorv10UIFigure);
            app.Label_ECR_est_9.Text = {'Estimated total ECR of '; ''};
            app.Label_ECR_est_10 = uilabel(app.EnergyConsumptionEstimatorv10UIFigure);
            app.Label_ECR_est_10.Text = 'base driving cycle';
            app.Label_ECR_est_11 = uilabel(app.EnergyConsumptionEstimatorv10UIFigure);
            app.Label_ECR_est_11.Text = {'Estimated total ECR of '; ''};
            app.Label_ECR_est_12 = uilabel(app.EnergyConsumptionEstimatorv10UIFigure);
            app.Label_ECR_est_12.Text = 'target driving cycle';
            app.Label_ECR_est_13 = uilabel(app.EnergyConsumptionEstimatorv10UIFigure);
            app.Label_ECR_est_13.Text = 'Wh/km';
            app.Label_ECR_est_14 = uilabel(app.EnergyConsumptionEstimatorv10UIFigure);
            app.Label_ECR_est_14.Text = 'Wh/km';
            % Show the figure after all components are created
            app.EnergyConsumptionEstimatorv10UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EasyEgyEst_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EnergyConsumptionEstimatorv10UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EnergyConsumptionEstimatorv10UIFigure)
        end
    end
end